classdef Fuzzy_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        CreatedbyCarolineHartLabel      matlab.ui.control.Label
        RobothasreachedthedestinationLamp  matlab.ui.control.Lamp
        RobothasreachedthedestinationLabel  matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        EnterDestinationCoordinatesLabel  matlab.ui.control.Label
        EnterStartCoordinatesLabel      matlab.ui.control.Label
        dest_Ypos                       matlab.ui.control.NumericEditField
        YPositionEditField_2Label       matlab.ui.control.Label
        dest_Xpos                       matlab.ui.control.NumericEditField
        XPositionEditField_3Label       matlab.ui.control.Label
        start_Ypos                      matlab.ui.control.NumericEditField
        YPositionEditFieldLabel         matlab.ui.control.Label
        start_Xpos                      matlab.ui.control.NumericEditField
        XPositionEditFieldLabel         matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        CurrentAnglularSpeedGauge       matlab.ui.control.SemicircularGauge
        CurrentAnglularSpeedGaugeLabel  matlab.ui.control.Label
        CurrentSpeedGauge               matlab.ui.control.LinearGauge
        CurrentSpeedGaugeLabel          matlab.ui.control.Label
        Robot                           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        initial_X; % initial X-position of robot
        initial_Y; % initial Y-position of robot
        dest_X = 95; % destination X-position
        dest_Y = 95; % destination Y-position
        theta = 0; % angle in which robot is facing

        current_X_location = 1; % current location of robot
        current_Y_location = 1;
        current_angle = pi; % current angle of robot

        phi; % angle between robot and destination
        distance; % distance between robot and destination

        angular_velocity = 0; % linear velocity of robot
        linear_velocity = 0; % angular velocity of robot

        atDestination = false; % boolean value for robot at destination

        fis = readfis('Fuzzy2'); % fuzzy logic variable

        robot; % variable to hold robot object for plot
        robotLine; % variable to hold robot direction vector for plot

        isPaused = false; % variable to hold execution state
    end
    
    methods (Access = private)


        function calculateNextGen(app)
            calculatePhi(app);
            app.phi = app.phi(1);
            calculateDistance(app);
            res = evalfis(app.fis, [app.distance, app.phi]);
            app.linear_velocity = res(1);
            app.angular_velocity = res(2);
        end

        % setInitialX
        %
        % setter function which is called when the user inputs a value for
        % the Start X-Position
        function setInitialX(app, value)
            app.current_X_location = value;
        end

        % setInitialY
        %
        % setter function which is called when the user inputs a value for
        % the Start X-Position
        function setInitialY(app, value)
            app.current_Y_location = value;
        end

        % setDestX
        %
        % setter function which is called when the user inputs a value for
        % the Start X-Position
        function setDestX(app, value)
            app.dest_X = value;
        end

        % setDestY
        %
        % setter function which is called when the user inputs a value for
        % the Start X-Position
        function setDestY(app, value)
            app.dest_Y = value;
        end

        % calculatePhi
        %
        % This function calculates the angle between the robot's current
        % angle and the destination
        function calculatePhi(app)
            currentLocationVec = [app.dest_X - app.current_X_location, app.dest_Y - app.current_Y_location];
            destinationVec = [cos(app.current_angle), sin(app.current_angle)];
            %destinationVec = [cos(app.current_angle)- app.current_X_location, sin(app.current_angle) - app.current_Y_location];

            temp = dot(destinationVec, currentLocationVec)/(norm(destinationVec)*norm(currentLocationVec));
            if (temp >= 1)
                temp = 1;
            end
            if (temp <= -1)
                temp = -1;
            end
            app.phi = acos(temp);
            app.phi = app.phi(1);
        end

        % calculateDistance
        %
        % calculates the distance between two given points
        function calculateDistance(app)
            temp_X = app.current_X_location - app.dest_X;
            temp_Y = app.current_Y_location - app.dest_Y;
            temp_X = temp_X^2;
            temp_Y = temp_Y^2;
            app.distance = sqrt(temp_X + temp_Y);
        end


        % getNextLocation
        %
        % This function calculates the robot's next (x,y) location
        % based on its current location, current theta, and linear_velocity
        function getNextLocation(app)
            delta_t = 0.100; % change in time = 100 ms
            delta_x = app.linear_velocity*delta_t;
            delta_x_x = delta_x * cos(app.current_angle);
            delta_x_y = delta_x * sin(app.current_angle);
            app.current_X_location = app.current_X_location + delta_x_x;
            app.current_Y_location = app.current_Y_location + delta_x_y;
            app.current_X_location
            app.current_Y_location
        end

        % getNextTheta
        %
        % This function calculates the robot's next value of theta
        % based on its current theta and angular_velocity.
        %
        function getNextTheta(app)
            app.current_angle = app.current_angle + app.angular_velocity; 
        end

        % isAtDestination
        %
        % This function returns true if the robot is at the destination.
        % 
        % parameters: currentLocation
        %             destination
        % return: boolean, true if robot is at destination +/- 0.05, false otherwise
        %
        function atDestination = isAtDestination(app)
            episilon = 0.25;
            calculateDistance(app);
            app.atDestination = false;
            if (abs(app.distance) <= episilon)
                app.atDestination = true;
            end
            atDestination = app.atDestination;
            if (atDestination)
                app.RobothasreachedthedestinationLamp.Color = [0.39 0.83 0.07];
            end

        end

        % withinBounds
        %
        % adjusts the robot's coordinates such that they are still within the 
        % [1,100]x[1,100] range and adjusts the robot's angle such that it is
        % still within the [-pi, pi] range.
        %
        % parameters: currentLocation
        %             currentAngle
        % return: currentLocation
        %         currentAngle  
        %
        function withinBounds(app) % [app.current_X_location, app.current_Y_location]
            if (app.current_X_location > 100)
                app.current_X_location = 100;
            end
            if (app.current_Y_location > 100)
                app.current_Y_location = 100;
            end
            if (app.current_X_location < 1)
                app.current_X_location = 1;
            end
            if (app.current_Y_location < 1)
                app.current_Y_location = 1;
            end
            if (app.current_angle < -1*pi)
                app.current_angle = -1*pi;
            end
            if (app.current_angle > pi)
                app.current_angle = pi;
            end
            if (app.current_angle == 2*pi || app.current_angle == -2*pi)
                app.current_angle = 0;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: start_Xpos
        function start_XposValueChanged(app, event)
            value = app.start_Xpos.Value;
            setInitialX(app, value); % set initial x-position
        end

        % Value changed function: start_Ypos
        function start_YposValueChanged(app, event)
            value = app.start_Ypos.Value;
            setInitialY(app, value) % set initial y-position
        end

        % Value changed function: dest_Xpos
        function dest_XposValueChanged(app, event)
            value = app.dest_Xpos.Value;
            setDestX(app, value); % set destination x-position
        end

        % Value changed function: dest_Ypos
        function dest_YposValueChanged(app, event)
            value = app.dest_Ypos.Value;
            setDestY(app, value); % set destination y-position
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            hold(app.Robot, "on");
            app.robot = scatter(app.Robot, app.current_X_location, app.current_Y_location, 'filled', 'b');
            scatter(app.Robot, app.dest_X, app.dest_Y, 'xk');
            hold(app.Robot, "off");

            while(~isAtDestination(app)) 
                calculateNextGen(app); % updates app.linear_velocity and app.angular_velocity
                
                % calculate the next theta and (x,y) coordinates from the velocities
                getNextLocation(app);                   % calculate next (x,y) location from linear velocity
                getNextTheta(app);                      % calculate next theta from angular velocity

                withinBounds(app);                      % ensure robot is within [0,100]x[0,100] plane
                calculateDistance(app);                 % Calculate the distance between the robot and its destination
                visualize(app);
            end
            if (isAtDestination(app))
                % set gauges to zero to reflect that robot has stopped
                app.CurrentAnglularSpeedGauge.Value = 0; 
                app.CurrentSpeedGauge.Value = 0;
            end
            
            % Handle the plot and gauge visualizations
            function visualize(app)
                pause(0.100); % pause execution for 100 ms
                delete(app.robot);
                hold(app.Robot, "on");
                app.robot = scatter(app.Robot, app.current_X_location, app.current_Y_location, 'filled', 'b');
                hold(app.Robot, "off");

                app.CurrentAnglularSpeedGauge.Value = app.angular_velocity;
                app.CurrentSpeedGauge.Value = app.linear_velocity;
                drawnow;
            end

        end

        % Callback function
        function PauseExecutionSwitchValueChanged(app, event)

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {219, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.XPositionEditFieldLabel = uilabel(app.LeftPanel);
            app.XPositionEditFieldLabel.Text = 'X-Position';
            app.start_Xpos = uieditfield(app.LeftPanel, 'numeric');
            app.start_Xpos.Limits = [1 100];
            app.start_Xpos.ValueChangedFcn = createCallbackFcn(app, @start_XposValueChanged, true);
            app.start_Xpos.Value = 1;
            app.YPositionEditFieldLabel = uilabel(app.LeftPanel);
            app.YPositionEditFieldLabel.Text = 'Y-Position';
            app.start_Ypos = uieditfield(app.LeftPanel, 'numeric');
            app.start_Ypos.Limits = [1 100];
            app.start_Ypos.ValueChangedFcn = createCallbackFcn(app, @start_YposValueChanged, true);
            app.start_Ypos.Value = 1;
            app.XPositionEditField_3Label = uilabel(app.LeftPanel);
            app.XPositionEditField_3Label.Text = 'X-Position';
            app.dest_Xpos = uieditfield(app.LeftPanel, 'numeric');
            app.dest_Xpos.Limits = [1 100];
            app.dest_Xpos.ValueChangedFcn = createCallbackFcn(app, @dest_XposValueChanged, true);
            app.dest_Xpos.Value = 95;
            app.YPositionEditField_2Label = uilabel(app.LeftPanel);
            app.YPositionEditField_2Label.Text = 'Y-Position';
            app.dest_Ypos = uieditfield(app.LeftPanel, 'numeric');
            app.dest_Ypos.Limits = [1 100];
            app.dest_Ypos.ValueChangedFcn = createCallbackFcn(app, @dest_YposValueChanged, true);
            app.dest_Ypos.Value = 95;
            app.EnterStartCoordinatesLabel = uilabel(app.LeftPanel);
            app.EnterStartCoordinatesLabel.Text = 'Enter Start Coordinates';
            app.EnterDestinationCoordinatesLabel = uilabel(app.LeftPanel);
            app.EnterDestinationCoordinatesLabel.Text = 'Enter Destination Coordinates';
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.RobothasreachedthedestinationLabel = uilabel(app.LeftPanel);
            app.RobothasreachedthedestinationLabel.Text = {'Robot has reached'; 'the destination'};
            app.RobothasreachedthedestinationLamp = uilamp(app.LeftPanel);
            app.CreatedbyCarolineHartLabel = uilabel(app.LeftPanel);
            app.CreatedbyCarolineHartLabel.Text = 'Created by Caroline Hart';
            app.RightPanel = uipanel(app.GridLayout);
            app.Robot = uiaxes(app.RightPanel);
            title(app.Robot, 'Robot')
            xlabel(app.Robot, 'X-Position')
            ylabel(app.Robot, 'Y-Position')
            zlabel(app.Robot, 'Z')
            app.Robot.XLim = [1 100];
            app.Robot.YLim = [1 100];
            app.Robot.XTick = [1 50 100];
            app.Robot.XTickLabel = {'1'; '50'; '100'};
            app.Robot.YTick = [1 50 100];
            app.Robot.YTickLabel = {'1'; '50'; '100'};
            app.Robot.ZTick = 25;
            app.CurrentSpeedGaugeLabel = uilabel(app.RightPanel);
            app.CurrentSpeedGaugeLabel.Text = 'Current Speed';
            app.CurrentSpeedGauge = uigauge(app.RightPanel, 'linear');
            app.CurrentSpeedGauge.Limits = [0 10];
            app.CurrentAnglularSpeedGaugeLabel = uilabel(app.RightPanel);
            app.CurrentAnglularSpeedGaugeLabel.Text = 'Current Anglular Speed';
            app.CurrentAnglularSpeedGauge = uigauge(app.RightPanel, 'semicircular');
            app.CurrentAnglularSpeedGauge.Limits = [-0.5 0.5];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fuzzy_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end